classdef Tapestry_ToolKit_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                  matlab.ui.Figure
        File                     matlab.ui.container.Menu
        Open_ref_Image           matlab.ui.container.Menu
        Load_Analysis            matlab.ui.container.Menu
        Save_as                  matlab.ui.container.Menu
        deformed_Image_folder    matlab.ui.container.Menu
        Exit_menu                matlab.ui.container.Menu
        Load_current_analysis    matlab.ui.container.Menu
        save_current_analysis    matlab.ui.container.Menu
        Analysis_type_selection  matlab.ui.container.Menu
        Static                   matlab.ui.container.Menu
        Explicit_dynamics        matlab.ui.container.Menu
        Material_Behaviour       matlab.ui.container.Menu
        elastic                  matlab.ui.container.Menu
        hyper_elastic            matlab.ui.container.Menu
        Load_type                matlab.ui.container.Menu
        displacement             matlab.ui.container.Menu
        Gravity                  matlab.ui.container.Menu
        Image_processing         matlab.ui.container.Menu
        ROI_select               matlab.ui.container.Menu
        Scale_image              matlab.ui.container.Menu
        Mesh_Density             matlab.ui.container.Menu
        slits                    matlab.ui.container.Menu
        stitches                 matlab.ui.container.Menu
        dic_software             matlab.ui.container.Menu
        Vic                      matlab.ui.container.Menu
        Ncorr                    matlab.ui.container.Menu
        Results                  matlab.ui.container.Menu
        dic_fea_plot             matlab.ui.container.Menu
        y_disp_plot              matlab.ui.container.Menu
        x_disp_plot              matlab.ui.container.Menu
        y_strain                 matlab.ui.container.Menu
        x_strain                 matlab.ui.container.Menu
        shear_strain             matlab.ui.container.Menu
        Percent_error            matlab.ui.container.Menu
        y_disp_percent           matlab.ui.container.Menu
        x_disp_percent           matlab.ui.container.Menu
        y_strain_percent         matlab.ui.container.Menu
        x_strain_percent         matlab.ui.container.Menu
        shear_strain_percent     matlab.ui.container.Menu
        difference_plot          matlab.ui.container.Menu
        y_disp_diff              matlab.ui.container.Menu
        x_disp_diff              matlab.ui.container.Menu
        y_strain_diff            matlab.ui.container.Menu
        x_strain_diff            matlab.ui.container.Menu
        shear_diff               matlab.ui.container.Menu
        global_aver              matlab.ui.container.Menu
        y_disp_glob              matlab.ui.container.Menu
        x_disp_glob              matlab.ui.container.Menu
        y_strain_glob            matlab.ui.container.Menu
        x_strain_glob            matlab.ui.container.Menu
        shear_strain_glob        matlab.ui.container.Menu
        Recommend                matlab.ui.container.Menu
        RefImage_panel           matlab.ui.container.Panel
        axes1                    matlab.ui.control.UIAxes
        uipanel2                 matlab.ui.container.Panel
        deformed_Image           matlab.ui.control.Button
        Postprocess              matlab.ui.control.Button
        Speckle_Image            matlab.ui.control.Button
        pushdecrease             matlab.ui.control.Button
        resultNum_disp           matlab.ui.control.EditField
        push_Increase            matlab.ui.control.Button
        text3                    matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
    end
    
    properties (Access = public)
        Property2 % Description
        name; % reference image name
        ref_Image; % reference image handle
        actal_dimensions; % reference image dimesion handle
        Slits;          % slit in the image handle
        SticthedElement; % stitching patch handle
        select_Result_Components; result_components; % result components handle
        UpgradeRequired;        % Abaqus odb file upgrade handle
        realWidth;  %  reference image real width
        realHeight; %  reference image real Height
        DIC_SOFTWARE; % handle for DIC software used
        NumMaterials; % number of materials in the image
        Explicit; % for explicit analysis
        static; % for static analysis
        Density; gravity; % density and gravity user values
        Youngs ; % Young's modulus
        Poissons; % Poisson's ratio
        Material_Prop; % Material behavior handle
        mu;alpha;D;  % Hyperelastic material properties
        strch;ROI; %strch= stretch value for displacement load from user, ROI= region of interest handle
        time;Scale_val; % time=analysis time, Scale_val=Image scaling value from user
        Tabular_Load;Displacement_load;Gravity_load;Data_table; % Load type handles
        no_images; Meshdensity; % user specified number of deformed images required and mesh density values
        tilt;Compare;ref_speckle_Image;
        loaded_analysis;  FEA_SOFTWARE; OBD; parallel;
         Plot_Mesh; % handle for mesh plotting
        %Result propoerties
         abaqus_results;Dic_results_fabric;Dic_results_speckle;Speckle_difference;
         fabric_difference;fabric_percentError;Speckle_percentError;Global_Ave_PercentError_speckle         
         Global_Ave_PercentError_fabric; Global_Aver_Val_fabric;Global_Aver_Val_speckle;       
        I_Fabric; I_Speckle;xgrid;ygrid; Dic_results; abaqus_var;Result_type;titl;
        
        % handless for adding tears in the reference image
        SlittedElements;SlitType ; StitchedElements;
        % handles for assigning material properties for patched regions
         Patch_mu;Patch_alpha;Patch_D;PatchedYoungs;PatchedPoissons
                                                                        
    end


    methods (Access = private)
        function SaveState(app)
            
            prompt = 'Enter Filename';
            dlgtitle = 'Analysis Name';
            dims=[2 40];
            answerN = inputdlg(prompt,dlgtitle,dims);
            if ~isempty(answerN)
                answerN= string(answerN);
                state.name =app.name;
                state.refImage= app.ref_Image;
            
            
                checkAnalysistype=any(ismember(fields(app),'NumMaterials'));
                if checkAnalysistype==1
                    state.NumMat= app.NumMaterials;
                    state.Exp=    app.Explicit;
                    state.Stat= app.Static;
                    if state.Exp==1
                        state.Den=app.Density;
                    end
                end
            
                checkBehaviour=any(ismember(fields(app),'Material_Prop'));
                if checkBehaviour==1
                    state.MatBehaviour= app.Material_Prop;
                    if strcmp(app.Material_Prop,'Linear_elastic')
                        state.YoungVal=app.Youngs;
                        state.PoissonVal=app.Poissons;
                    elseif strcmp(app.Material_Prop,'Hyper_elastic')
                        state. muVals=  app.mu;
                        state.AlphaVals=  app.alpha;
                        state.DVals= app.D;
                    end
                end
            
            
                checkLoadtype = any(ismember(fields(app),'Tabular_Load'));
                if checkLoadtype==1
                    state.TabLoad = app.Tabular_Load;
                    state. DispLoad= app.Displacement_load;
                    state.GravLoad= app.Gravity_load;
            
                    if  state.TabLoad == 0 &&  state.DispLoad==1
                        state.strch=  app.strch ;
                    elseif state.TabLoad ==0 && state.GravLoad==1
                        state.GravVal=app.Gravity;
                        state.Den=app.Density;
                        state.strch=  0;
                    elseif  state.TabLoad==1
                        state. strch=  0;
                        state.tabledata=app.Data_table;
                    end
                end
            
                checkNumImages = any(ismember(fields(app),'no_images'));
                if checkNumImages==1
                    state.noImages=app.no_images;
                end
            
                checkTime = any(ismember(fields(app),'time'));
                if checkTime==1
                    state.Time = app.time;
                end
            
            
                CheckMeshDensity=any(ismember(fields(app),'Meshdensity'));
                if CheckMeshDensity==1
                    state. meshDenVal=app.Meshdensity;
                end
            
                CheckScalingValue=any(ismember(fields(app),'Scale_val'));
                if CheckScalingValue==1
                    state. scaleVal=app.Scale_val;
                end
            
                CheckROI=any(ismember(fields(app),'ROI'));
                if CheckROI==1
                    state. ROI=app.ROI;
                end
            
            
                tit=char( [answerN,'.mat']);
                % write the state to file
                save(tit,'state');
                uiwait(msgbox('Analysis saved','Saved'))
            end
        end


        function Image_Pre_Edge_Detect(ROI,app)
                
                
                if app.actal_dimensions==1
                    realWidth=app.realWidth *1000; realHeight=app.realHeight *1000;
                    [I] =app.ref_Image;
                    [I]= imresize(I,[realHeight,realWidth]);
                else
                    [I] =app.ref_Image;
                end
                
                Resiz_Val = app.Scale_val;
                %  I= tit;
                %----------------------------Edge Detection-------------------------------%
                
                if ROI==1   % A ROI is to be manually selected from the whole image
                    I = imresize(I,Resiz_Val, 'cubic');
                    
                    set(app.RefImage_panel,'Title',' From the new figure, select the ROI & then double click to continue', 'FontSize',9, 'FontWeight',' bold')
                    fig_ROI= figure ('Name','Select the ROI & then double click to continue','MenuBar','none','NumberTitle','off');
                
                    imshow(app.ref_Image)
                    h = imrect;
                    position = wait(h);
                    position = round(position);
                    RGB = I(position(2):position(2)+position(4),position(1):position(1)+position(3),:);
                    imshow(RGB,'Parent', app.axes1);
                    app.ref_Image=RGB;
                    set(app.RefImage_panel,'Title','Reference Image', 'FontSize',9,'FontWeight',' normal')
                    close (fig_ROI);
                else            % If the whole image for analysis is the region of interest
                  
                    I = imresize(I,Resiz_Val, 'cubic');
                    RGB = I;
                    app.ref_Image= I;
                    
                    %   close
                end
                
                if size(RGB,3)==3
                    RGB = rgb2gray(RGB);
                end
                J = flip(RGB);
                
                J_Filt = imgaussfilt(J,1);
                
                J_Edges = edge(J_Filt,'Canny');
                
                
                boundary_thickness = 3;%[pixel]
                
                SE = strel('disk',1);
                
                J_Dil = imdilate(J_Edges,SE);
                
                position_objects = J_Dil + 1;
                Taps_Drawings{1,1} = position_objects(:);
                Taps_Drawings{1,2} = J;
                
                save('Taps_Drawings.mat','Taps_Drawings');


            end


        function coord = getLine(app)
            
            [x(1,1),y(1,1)]=ginput(1);           %IMAGE Cordinate
            
            hold on
            plot(x(1,1),y(1,1),'+b')
            
            [x(2,1),y(2,1)]=ginput(1);
            
            hold on
            plot(x(2,1),y(2,1),'+b')
            
            % drawnow
            xx =[x(1,1),x(2,1)]; yy =[y(1,1),y(2,1)];
            x1= min(x); x2= max(x); y1 = min(y); y2 = max(y);
            plotx=plot(xx,yy,'w-');
            set(plotx,'LineWidth',3)
            coord = [x1 x2 y1 y2];
        end


        function elem = user_selct_Square(app)

            [x(1,1),y(1,1)]=ginput(1);           %IMAGE Cordinate
            % y(1,1) = size(RGB,1)-y(1,1);           %FEA coordinate
            hold on
            plot(x(1,1),y(1,1),'+b')
            
            [x(2,1),y(2,1)]=ginput(1);
            % y(2,1) = size(RGB,1)-y(2,1);
            hold on
            plot(x(2,1),y(2,1),'+b')
            
            drawnow
            
            xmin = min(x);
            xmax = max(x);
            ymin = min(y);
            ymax = max(y);
            
            lowerline=[xmin ymin; xmax ymin];
            upperline=[xmin ymax; xmax ymax];
            leftline=[xmin ymin; xmin ymax];
            rightline=[xmax ymin; xmax ymax];
            
            plot(lowerline(:,1),lowerline(:,2),'-b')
            plot(upperline(:,1),upperline(:,2),'-b')
            plot(leftline(:,1),leftline(:,2),'-b')
            plot(rightline(:,1),rightline(:,2),'-b')
            elem =[xmin xmax ymin ymax];
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Tapestry_ToolKit_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to Tapestry_ToolKit (see VARARGIN)
%             
%             % Choose default command line output for Tapestry_ToolKit
%             
%             app.output = hObject;
%             set(0,'units','characters')
%             screen_pos=get(0, 'screensize');
%             ax=app.axes1;
%             height_Tapkit = 50;
%             width_Tapkit= 200;
%             left_Tapkit= ((screen_pos(3)-width_Tapkit)/2);
%             bottom_Tapkit = (screen_pos(4)-height_Tapkit)/2;
%             set(app.figure1,'Position',[left_Tapkit bottom_Tapkit width_Tapkit height_Tapkit])
%             panel_width=width_Tapkit-40; panel_height =height_Tapkit-4;
%             set(app.uipanel2,'Position',[20 10 25 (panel_height-24)])
%             set(app.RefImage_panel,'Position',[30 1 panel_width panel_height])
%             set(app.axes1,'Position',[0.1 0.1 panel_width panel_height], 'visible', 'off')
%             def_button_Pos = get(app.uipanel2,'Position');
%             def_button_Pos(1)=3; def_button_Pos(2)=5;
%             set(app.uipanel2,'Position',def_button_Pos)
            set(app.deformed_Image,'Enable','off')
            set(app.Postprocess,'Enable','off')
            set(app.deformed_Image_folder,'Enable','off')
            set(app.Mesh_Density,'Enable','off')
            set(app.Scale_image,'Enable','off')
            set(app.ROI_select,'Enable','off')
            set(app.Analysis_type_selection,'Enable','off')
            set(app.Material_Behaviour,'Enable','off')
            set(app.Load_type,'Enable','off')
            set(app.Speckle_Image,'Enable','off')
            set(app.dic_software,'Enable','off')
            set(app.Results,'Enable','off')
            set(app.Image_processing,'Enable','off')
            set(app.pushdecrease,'Visible','off')
            set(app.push_Increase,'Visible','off')
            set(app.resultNum_disp,'Visible','off')
            set(app.text3,'Visible','off')
            set(app.save_current_analysis,'Enable','off')
%             
%             % Enlarge figure to full screen.
%             %  set(gcf, 'units','normalized','outerposition',[0 0 1 1]);
%             
%             %  set(app.figure1, 'resize','on')
%             % Update handles structure
%             guidata(hObject, handles);
        end

        % Menu selected function: Exit_menu
        function Exit_menu_Callback(app, event)
            % hObject    handle to Exit_menu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
        end

        % Menu selected function: Explicit_dynamics
        function Explicit_dynamics_Callback(app, event)
            % hObject    handle to Explicit_dynamics (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            prompt = {'Enter Number of Materials in the Image', 'Enter Density Value'};
            dlgtitle = 'Number of Materials:';
            dims=[2 40];
            opts.Resize ='on';
            definput = {'1','1'};
            answer = inputdlg(prompt,dlgtitle,dims, definput,opts);
            NumMaterials = str2double(answer);
            
            if ~isempty(NumMaterials)
                NumMaterials = str2double(answer(1));
                den= str2double(answer(2));
                app.Density = den;
                app.NumMaterials=NumMaterials;
                app.Explicit=1;
                app.static=0;
                set(app.Material_Behaviour,'Enable','on')
            end
           
        end

        % Menu selected function: Gravity
        function Gravity_Callback(app, event)
            % hObject    handle to Gravity (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            Chek= app.Explicit;
            Dispbutton=questdlg('Is Gravity time dependent?','Gravity Confirmation','Yes','No','Yes');
            
            if isempty(Dispbutton)
                return
            end
            
            
            if (strcmp(Dispbutton,'No'))
            
                app.Gravity_load =1;
                app.Tabular_Load =0;
                app.Displacement_load =0;
                app.time=1;
                if Chek==1
                    prompt = {'Enter the gravity value'};
                    definput = {'1'};
                else   % not explicit
                    prompt = {'Enter the gravity value','Enter Density value'};
                    definput = {'1','1'};
                end
                dlgtitle = 'Gravity value';
                dims=[2 40];
                opts.Resize ='on';
                answer = inputdlg(prompt,dlgtitle,dims, definput,opts);
                if ~isempty(answer)
                    Grav = str2double(answer(1));
                    if Chek==0   % not explicit
                        Density = str2double(answer(2));
                        app.Density =   Density;
                    end
                    app.gravity =   Grav;
                    app.Tabular_Load =0;
                    set(app.Mesh_Density,'Enable','on')
                    set(app.Image_processing,'Enable','on')
                end
            else
            
                table_confirm = questdlg('How would you input time and load values?', 'Table Value', 'Read from a file', 'Type into a table', 'Read from a file');
                if isempty(table_confirm)
                    return
                end
            
            
                if Chek==0
                    prompt = {'Enter the total time value','Enter Density value'};
                    definput = {'1','1'};
                else  % explicit
                    prompt = {'Enter the total time value'};
                    definput = {'1'};
                end
                dlgtitle = 'Total time';
                dims=[2 40];
                opts.Resize ='on';
                definput = {'5','1'};
                answer = inputdlg(prompt,dlgtitle,dims, definput,opts);
            
                if isempty(answer)
                else
                    table_length = str2double(answer(1));
                    switch table_confirm
                        case 'Type into a table'
            
                            figg = uifigure('Position',[500 200 600 300]);
                            ut = uitable(figg);
                            ut.Position = [50 50 420 320];
                            ut.ColumnName = {'Time', 'Value'};
                            ut.ColumnEditable = true;
                            set(ut, 'data', ones( table_length,2));
                            waitfor(uibutton(figg,'push', 'Text', 'OK', 'Position', [100 20 50 20], 'ButtonPushedFcn',@(ub,event) getTabledata(ut,figg)));
                            data_table = getappdata(0,'Table_data');
                            app.Data_table=data_table;
            
                        case 'Read from a file'
            
                            %        table_file =readtable(uigetfile({'*txt; *xlsx; *xlsx;'}));
            
                            [filename, path] = uigetfile({'*txt;*xlsx;*xls;'});
                            if isequal(filename,0)
                            else
                                tilt_table =fullfile(path,filename);
                                %                 table_file =readtable(tilt_table);
                                table_file = dlmread(tilt_table);
                                app.Data_table=table_file;
            
                            end
                    end
                    app.Gravity_load =1;
                    if Chek==0
                        density= str2double(answer(2));
                        app.Density= density;
                    end
                    app.Displacement_load =0;
                    app.Tabular_Load =1;
                    app.time=table_length;
                    app.no_images=table_length;
                    set(app.Mesh_Density,'Enable','on')
                    set(app.Image_processing,'Enable','on')
                end
            
            end
            app.strch =  0;
           
        end

        % Menu selected function: Load_Analysis
        function Load_Analysis_Callback(app, event)
            
            % This function load a saved analysis results for plotting only
            
            
            cla (app.axes1,'reset')
            set(app.axes1, 'visible', 'off')
            set(app.RefImage_panel,'Title', '')
            set(app.resultNum_disp,'Visible','off')
            set(app.text3,'Visible','off')
            set(app.pushdecrease,'Visible','off')
            set(app.push_Increase,'Visible','off')
            set(app.RefImage_panel,'BackgroundColor','default')
            
            [Analysis, path] = uigetfile({'*mat'});
            
            if isequal(Analysis,0)
            
            else
            
               
                app.loaded_analysis= 1;
                Analysis_status=app.loaded_analysis;
                tilt =fullfile(path,Analysis);
                app.tilt=tilt;
            
                load(tilt,'no_images','DIC_SOFTWARE','compare','ref_Image','FEA_SOFTWARE','Result_Components','TabularLoad');
                imshow(ref_Image{1,1},'Parent',app.axes1);
                set(app.RefImage_panel,'Title', 'Reference Image')
                app.DIC_SOFTWARE =DIC_SOFTWARE;
                app.result_components=Result_Components;
                app.FEA_SOFTWARE=FEA_SOFTWARE;
                app.no_images=no_images; app.Compare=compare;
                app.Tabular_Load=TabularLoad;
                set(app.Results,'Enable','on')
                f= waitbar(1/5,'Please wait','Name','Loading Analysis');
                load (tilt)
                waitbar(4/5)
                if strcmp(compare, 'YES')
                    app.abaqus_results= abaqus_results;
                    app.Dic_results_fabric=Dic_results_fabric;
                    app.Dic_results_speckle=Dic_results_speckle;
                    app.Speckle_difference=Speckle_difference;
                    app.fabric_difference=fabric_difference;
                    app.fabric_percentError =fabric_percentError;
                    app.Speckle_percentError =Speckle_percentError;
                    app.Global_Ave_PercentError_speckle=Global_Aver_PercentError_speckle;
                    app.Global_Ave_PercentError_fabric=Global_Aver_PercentError_fabric;
                    app.Global_Aver_Val_fabric =Global_Aver_Val_fabric;
                    app.Global_Aver_Val_speckle =Global_Aver_Val_speckle;
                    app.I_Fabric=I_Fabric;
                    app.I_Speckle=I_Speckle;
                    app.xgrid=xgrid;
                    app.ygrid=ygrid;
                elseif  strcmp(compare, 'NO')
                    app.abaqus_results= abaqus_results;
                    app.Dic_results=Dic_results;
                    app.fabric_difference=fabric_difference;
                    app.fabric_percentError =fabric_percentError;
                    app.Global_Ave_PercentError_fabric=Global_Aver_PercentError;
                    app.Global_Aver_Val_fabric =Global_Aver_Val;
                    app.I_Fabric=I_Fabric;
                    app.xgrid=xgrid;
                    app.ygrid=ygrid;
                end
            
                %%%%%% Enable appropriate result component handles
            
                if size(app.result_components,2)<5
            
                    dispy= ismember(2,app.result_components);
                    if dispy==0
                        set(app.y_disp_plot,'enable','off')
                        set(app.y_disp_glob,'enable','off')
                        set(app.y_disp_diff,'enable','off')
                        set(app.y_disp_percent,'enable','off')
                    end
            
                    dispx= ismember(1,app.result_components);
                    if dispx==0
                        set(app.x_disp_plot,'enable','off')
                        set(app.x_disp_glob,'enable','off')
                        set(app.x_disp_diff,'enable','off')
                        set(app.x_disp_percent,'enable','off')
                    end
            
                    exx= ismember(3,app.result_components);
                    if exx==0
                        set(app.x_strain,'enable','off')
                        set(app.x_strain_glob,'enable','off')
                        set(app.x_strain_diff,'enable','off')
                        set(app.x_strain_percent,'enable','off')
                    end
            
                    eyy= ismember(4,app.result_components);
                    if eyy==0
                        set(app.y_strain,'enable','off')
                        set(app.y_strain_glob,'enable','off')
                        set(app.y_strain_diff,'enable','off')
                        set(app.y_strain_percent,'enable','off')
                    end
            
                    exy= ismember(5,app.result_components);
                    if exy==0
                        set(app.shear_strain,'enable','off')
                        set(app.shear_strain_glob,'enable','off')
                        set(app.shear_diff,'enable','off')
                        set(app.shear_strain_percent,'enable','off')
                    end


                end
            
            
                waitbar(5/5)
                delete(f)
               
                h=msgbox('Analysis Loading completed','Success');
                set(h, 'position', [500 300 190 50])
                ah = get( h, 'CurrentAxes' );
                ch = get( ah, 'Children' );
                set( ch, 'FontSize', 12 ); %makes text bigger
                uiwait(h)
            end
        end

        % Menu selected function: Load_current_analysis
        function Load_current_analysis_Callback(app, event)
            % This function loads user saved analysis state
            % hObject    handle to Exit_menu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            
            cla (app.axes1,'reset')
            set(app.axes1, 'visible', 'off')
            set(app.RefImage_panel,'Title', '')
            set(app.resultNum_disp,'Visible','off')
            set(app.text3,'Visible','off')
            set(app.pushdecrease,'Visible','off')
            set(app.push_Increase,'Visible','off')
            set(app.RefImage_panel,'BackgroundColor','default')
            
            [Filename, path] = uigetfile({'*mat'});
            
            if isequal(Filename,0)
                return
            else
                f= waitbar(1/5,'Please wait');
                tilt =fullfile(path,Filename);
                load(tilt);
                app.ref_Image =state.refImage;
                imshow(app.ref_Image);
                name =state.name;   %reference image name
                app.name=state.name;
                space =' ';
                set(app.RefImage_panel,'Title', ['Reference Image is',space,name])
                app.time= state.Time;
                checkAnalysistype= any(ismember(fields(state),'Exp'));
                if checkAnalysistype==1
                    set(app.Material_Behaviour,'Enable','on')
                    if state.Exp==1 ||  state.Stat==1
                        set(app.Analysis_type_selection,'Enable','on')
                        app.NumMaterials=state.NumMat;
                        app.Explicit=state.Exp;
                        app.Static=state.Stat;
                    end
                end
                waitbar(2/5)
                checkBehaviour= any(ismember(fields(state),'MatBehaviour'));
                if checkBehaviour==1
                    set(app.Material_Behaviour,'Enable','on')
                    set(app.Load_type,'Enable','on')
                    app.Material_Prop= state.MatBehaviour;
                    if strcmp(state.MatBehaviour,'Linear_elastic')
                        app.Youngs=state.YoungVal;
                        app.Poissons = state.PoissonVal;
                    elseif strcmp(state.MatBehaviour,'Hyper_elastic')
                        app.mu = state. muVals;
                        app.alpha = state.AlphaVals;
                        app.D = state.DVals;
                        app.Density=state.Den;
                    end
                end
                waitbar(3/5)
                checkLoadType = any(ismember(fields(state),'DispLoad'));
            
                if checkLoadType==1
                    set(app.Load_type,'Enable','on')
                    set(app.Image_processing,'Enable','on')
                    app.Tabular_Load = state.TabLoad;
                    app.Displacement_load= state.DispLoad;
                    app.Gravity_load= state.GravLoad;
            
                    if state.TabLoad ==0 &&  state.DispLoad==1
                        app.strch =state. strch;
                    elseif state.TabLoad ==0 && state.GravLoad==1
                        app.Gravity= state.GravVal;
                        app.Density=state.Den;
                        app.strch=  0;
                    elseif  state.TabLoad==1
                        app.strch=  0;
                        app.Data_table=state.tabledata;
                    end
            
                end
                waitbar(4/5)
                checkPreprocessMeshDensity = any(ismember(fields(state),'meshDenVal'));
            
                if checkPreprocessMeshDensity==1
                    set(app.Mesh_Density,'Enable','on')
                    set(app.Image_processing,'Enable','on')
                    app.Meshdensity=state. meshDenVal;
                    set(app.Scale_image,'Enable','on')
                end
            
                checkPreprocessScale = any(ismember(fields(state),'scaleVal'));
            
                if checkPreprocessScale==1
                    set(app.Scale_image,'Enable','on')
                    app.Scale_val=state. scaleVal;
                    set(app.ROI_select,'Enable','on')
                    set(app.dic_software,'Enable','on')
                    set(app.deformed_Image,'Enable','on')
                end
            
                CheckROI=any(ismember(fields(state),'ROI'));
                if CheckROI==1
                    app.ROI= state. ROI;
                end
            
                delete(tilt);
                set(app.save_current_analysis,'Enable','on')
                waitbar(5/5)
                delete(f)
            
                guidata(hObject,handles)
                uiwait(msgbox('Analysis Loaded','Loaded'))
            
                if CheckROI==1
                    uiwait(msgbox('Please select ROI','Select ROI', 'modal'))
                end
            
            end
        end

        % Menu selected function: Mesh_Density
        function Mesh_Density_Callback(app, event)
            % hObject    handle to Mesh_Density (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % uiwait(Mesh_density_value)
            % mesh_den_val = getappdata(0,'mesh_den_Val');
            % app.mesh_density_val=mesh_den_val;
            
            prompt = {'Enter Mesh density value'};
            dlgtitle = 'Mesh density value:';
            dims=[2 40];
            opts.Resize ='on';
            definput = {'3'};
            answer = inputdlg(prompt,dlgtitle,dims, definput,opts);
            meshDen_val = str2double(answer);
            if isempty(meshDen_val)
            else
                app.Meshdensity=meshDen_val;
                set(app.Scale_image,'Enable','on')
            
            end
            
           
        end

        % Menu selected function: Ncorr
        function Ncorr_Callback(app, event)
            % hObject    handle to Ncorr (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.DIC_SOFTWARE =1;
          
        end

        % Menu selected function: Open_ref_Image
        function Open_ref_Image_Callback(app, event)
            % hObject    handle to Open_ref_Image (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(app.deformed_Image,'Enable','off')
            set(app.Postprocess,'Enable','off')
            set(app.deformed_Image_folder,'Enable','off')
            set(app.Mesh_Density,'Enable','off')
            set(app.Scale_image,'Enable','off')
            set(app.ROI_select,'Enable','off')
            set(app.Analysis_type_selection,'Enable','off')
            set(app.Material_Behaviour,'Enable','off')
            set(app.Load_type,'Enable','off')
            set(app.Speckle_Image,'Enable','off')
            set(app.dic_software,'Enable','off')
            set(app.Results,'Enable','off')
            set(app.Image_processing,'Enable','off')
            set(app.pushdecrease,'Visible','off')
            set(app.push_Increase,'Visible','off')
            set(app.resultNum_disp,'Visible','off')
            set(app.text3,'Visible','off')
            set(app.save_current_analysis,'Enable','off')


            cla(app.axes1,'reset')
            set(app.axes1, 'visible', 'off')
            set(app.RefImage_panel,'Title', '')
            set(app.resultNum_disp,'Visible','off')
            set(app.text3,'Visible','off')
            set(app.pushdecrease,'Visible','off')
            set(app.push_Increase,'Visible','off')
            set(app.RefImage_panel,'BackgroundColor','default')
            [ref_Image, path] = uigetfile({'*png;*jpg;*tiff;*bmp;*tif'});
            if isequal(ref_Image,0)
            
            else
                tilt =fullfile(path,ref_Image);
                ref_Image=imread(tilt); space= ' ';
                imshow(ref_Image,'Parent',app.axes1)
                [~,name] = fileparts(tilt);
                app.name=name;
                set(app.RefImage_panel,'Title', ['Reference Image name is', space, name])
                app.ref_Image=ref_Image;
                set(app.Analysis_type_selection,'Enable','on')
                set(app.save_current_analysis,'Enable','on')
            
            
                ImgDimension=questdlg('Do you know the physical tapestry dimensions in meter?','Dimension','Yes','No','Yes');
                if isempty(ImgDimension)
                    return
                end
            
                if (strcmp(ImgDimension,'No'))
                    app.actal_dimensions=0;
                elseif (strcmp(ImgDimension,'Yes'))
                    app.actal_dimensions=1;
                    prompt = {'Enter height in meters', 'Enter width in meters'};
                    dlgtitle = 'Physical dimensions';
                    dims=[2 40];
                    opts.Resize ='on';
                    definput = {'1','1'};
                    answer = inputdlg(prompt,dlgtitle,dims, definput,opts);
                    Dimensions = str2double(answer);
            
                    if isempty(Dimensions)
                        return
                    else
                        app.realWidth=Dimensions(2);
                        app.realHeight=Dimensions(1);
                    end
                end
                app.Slits=0;                % Initial slits and patches to 0 unless user later add either
                app.SticthedElement=0;
                app.select_Result_Components=0;  % set the selection of desired results component to 0
                app.UpgradeRequired=0;        % Set upgrading of Abaqus odb file to 0
               
            
                if exist('Fabric_Images')
                    FabricFolder='Fabric_Images';
                    folderFiles = dir(fullfile(FabricFolder,'*.*'));
            
                    for fileNum = 1 : length(folderFiles)
                        Delfile = fullfile(FabricFolder, folderFiles(fileNum).name);
                        delete(Delfile);
                    end
                    rmdir('Fabric_Images')
                end
            
            
                if exist('Speckle_Images')
                    SpeckleFolder='Speckle_Images';
                    folderFiles = dir(fullfile(SpeckleFolder,'*.*'));
            
                    for fileNum = 1 : length(folderFiles)
                        Delfile = fullfile(SpeckleFolder, folderFiles(fileNum).name);
                        delete(Delfile);
                    end
                    rmdir('Speckle_Images')
                end
            
            
                if exist('ABAQUS_SIMULATIONS/DATA')
                    SimFolder='ABAQUS_SIMULATIONS/DATA';
                    folderFiles = dir(fullfile(SimFolder,'*.*'));
            
                    for fileNum = 1 : length(folderFiles)
                        Delfile = fullfile(SimFolder, folderFiles(fileNum).name);
                        delete(Delfile);
                    end
                    rmdir('ABAQUS_SIMULATIONS/DATA')
                end
            
            
                if exist('ABAQUS_SIMULATIONS')
                    SimFolder='ABAQUS_SIMULATIONS';
                    folderFiles = dir(fullfile(SimFolder,'*.*'));
            
                    for fileNum = 1 : length(folderFiles)
                        Delfile = fullfile(SimFolder, folderFiles(fileNum).name);
                        delete(Delfile);
                    end
                    try
                    rmdir('ABAQUS_SIMULATIONS')
                    catch
                    end
                end


                if exist('Delta_Shifts.mat')
                    delete('Delta_Shifts.mat')
                end
            
                if exist('MatMap.mat')
                    delete('MatMap.mat')
                end
            
                if exist('Taps_Drawings.mat')
                    delete('Taps_Drawings.mat')
                end
            
                if exist('Taps_Drawings_Fabric.mat')
                    delete('Taps_Drawings_Fabric.mat')
                end
                clc
            
            end
        end

        % Button pushed function: Postprocess
        function Postprocess_Callback(app, event)
            % hObject    handle to Postprocess (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            cla (app.axes1,'reset');         % Reset axis in case processing immediately after viewing results
            set(app.RefImage_panel,'Title', ['Reference Image name is',' ',app.name])
            imshow(app.ref_Image,'Parent',app.axes1)    % Just show image
            % set all results component buttons on
            set(app.y_disp_plot,'enable','on')
            set(app.y_disp_glob,'enable','on')
            set(app.y_disp_diff,'enable','on')
            set(app.y_disp_percent,'enable','on')
            set(app.x_disp_plot,'enable','on')
            set(app.x_disp_glob,'enable','on')
            set(app.x_disp_diff,'enable','on')
            set(app.x_disp_percent,'enable','on')
            set(app.x_strain,'enable','on')
            set(app.x_strain_glob,'enable','on')
            set(app.x_strain_diff,'enable','on')
            set(app.x_strain_percent,'enable','on')
            set(app.y_strain,'enable','on')
            set(app.y_strain_glob,'enable','on')
            set(app.y_strain_diff,'enable','on')
            set(app.y_strain_percent,'enable','on')
            set(app.shear_strain,'enable','on')
            set(app.shear_strain_glob,'enable','on')
            set(app.shear_diff,'enable','on')
            set(app.shear_strain_percent,'enable','on')
            
            set(app.resultNum_disp,'Visible','off')
            set(app.text3,'Visible','off')
            set(app.pushdecrease,'Visible','off')
            set(app.push_Increase,'Visible','off')
            set(app.RefImage_panel,'BackgroundColor','default')
            
            
            %      if app.select_Result_Components==0
            
            Confirm=questdlg('Do you want all displacement and strain components results?','Confirm','Yes','No','Yes');
            if isempty(Confirm)   % User did not make a choice
                return
            end
            switch Confirm
                case 'Yes'
                    app.result_components=[1 2 3 4 5];
                case 'No'
                    list={'U','V','Exx','Eyy','Exy'};
                    Result_Component = listdlg('ListString',list,'ListSize',[240 120],'Name', 'Select result type');
                    app.result_components = Result_Component;
            
            end
            %disable handles for result components not required
            if size(app.result_components,2)<5
            
                dispy= ismember(2,app.result_components);
                if dispy==0
                    set(app.y_disp_plot,'enable','off')
                    set(app.y_disp_glob,'enable','off')
                    set(app.y_disp_diff,'enable','off')
                    set(app.y_disp_percent,'enable','off')
                end
            
                dispx= ismember(1,app.result_components);
                if dispx==0
                    set(app.x_disp_plot,'enable','off')
                    set(app.x_disp_glob,'enable','off')
                    set(app.x_disp_diff,'enable','off')
                    set(app.x_disp_percent,'enable','off')
                end
            
                exx= ismember(3,app.result_components);
                if exx==0
                    set(app.x_strain,'enable','off')
                    set(app.x_strain_glob,'enable','off')
                    set(app.x_strain_diff,'enable','off')
                    set(app.x_strain_percent,'enable','off')
                end
            
                eyy= ismember(4,app.result_components);
                if eyy==0
                    set(app.y_strain,'enable','off')
                    set(app.y_strain_glob,'enable','off')
                    set(app.y_strain_diff,'enable','off')
                    set(app.y_strain_percent,'enable','off')
                end
            
                exy= ismember(5,app.result_components);
                if exy==0
                    set(app.shear_strain,'enable','off')
                    set(app.shear_strain_glob,'enable','off')
                    set(app.shear_diff,'enable','off')
                    set(app.shear_strain_percent,'enable','off')
                end


            end
            confirm=questdlg('Do you want to use parallel program for the analysis?','confirm','Yes','No','Yes');
            if isempty(confirm)   % User did not make a choice
                return
            end
            switch confirm
                case 'Yes'
                    app.parallel=1;
                case 'No'
                    app.parallel=0;
            end
            app.select_Result_Components=1;
            
            %   end
            
             try
                if app.Meshdensity<=6
                    deNum=10;       %  number of interpolation points that determines pixel locations along a element length
                elseif app.Meshdensity<=20
                    deNum=40;
                else
                    deNum=120;
                end
                DIC_SOFTWARE= app.DIC_SOFTWARE;
                FEA_SOFTWARE=app.FEA_SOFTWARE;
                imshow(app.ref_Image,'Parent',app.axes1)
                image_file_exist= any(size(dir(['Fabric_Images' '/*.tiff' ]),1));
                mat_file_exist=any(size(dir(['Fabric_Images' '/*.mat' ]),1));
                TEARS= app.Slits;
            
                if mat_file_exist && image_file_exist
                    no_images = app.no_images;
                    Compare = app.Compare;
            
                    %Make sure that speckle result and image files exist
                    if strcmp(Compare, 'YES')
                        Speckle_image_file_exist= any(size(dir(['Speckle_Images' '/*.tiff' ]),1));
                        Speckle_mat_file_exist=any(size(dir(['Speckle_Images' '/*.mat']),1));
            
                        if Speckle_image_file_exist==0 || Speckle_mat_file_exist==0
            
                            uiwait(msgbox('Either speckle deformed images or the speckle image DIC result files are not in the Speckle_Images folder'))
                            return
                        end
                    end
            
                    f = waitbar(0,'Please wait, generating results');
                    Process_Results(DIC_SOFTWARE,no_images,Compare,FEA_SOFTWARE,TEARS,deNum,app.result_components,app.parallel,app.result_components,app.Tabular_Load )
                    load('Results.mat')
                    if strcmp(Compare, 'YES')
                        app.abaqus_results= abaqus_results;
                        app.Dic_results_fabric=Dic_results_fabric;
                        app.Dic_results_speckle=Dic_results_speckle;
                        app.Speckle_difference=Speckle_difference;
                        app.fabric_difference=fabric_difference;
                        app.fabric_percentError =fabric_percentError;
                        app.Speckle_percentError =Speckle_percentError;
                        app.Global_Ave_PercentError_speckle=Global_Aver_PercentError_speckle;
                        app.Global_Ave_PercentError_fabric=Global_Aver_PercentError_fabric;
                        app.Global_Aver_Val_fabric =Global_Aver_Val_fabric;
                        app.Global_Aver_Val_speckle =Global_Aver_Val_speckle;
                        app.I_Fabric=I_Fabric;
                        app.I_Speckle=I_Speckle;
                        app.xgrid=xgrid;
                        app.ygrid=ygrid;
                    elseif strcmp(Compare, 'NO')
                        app.abaqus_results= abaqus_results;
                        app.Dic_results=Dic_results;
                        app.fabric_difference=fabric_difference;
                        app.fabric_percentError =fabric_percentError;
                        app.Global_Ave_PercentError_fabric=Global_Aver_PercentError;
                        app.Global_Aver_Val_fabric =Global_Aver_Val;
                        app.I_Fabric=I_Fabric;
                        app.xgrid=xgrid;
                        app.ygrid=ygrid;
                    end
            
                    delete(f)
                    set(app.Results,'Enable','on')
                    uiwait(msgbox('Analysis Completed','Success'))
                    
                else
                    if mat_file_exist==0
                        uiwait(msgbox('Please copy DIC results in Matlab format to the deform Image folder'))
                    elseif image_file_exist ==0
                        uiwait(msgbox('There are no deform images in the Deformed Image folder'))
                    end
                end
            
            catch
            
                h=msgbox('Please select the DIC software used in the DIC analysis','DIC SOftware','error');
                set(h, 'position', [500 300 350 60])
                uiwait(h)
            
                return
             end
        end

        % Menu selected function: ROI_select
        function ROI_select_Callback(app, event)
            % hObject    handle to ROI_select (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            ROIbutton=questdlg(' Do you want to use the whole image as ROI?','ROI Confirmation','Yes','No','Yes');
            if (strcmp(ROIbutton,'No'))
                ROI=1;
            else
                ROI =0;
            end
            Image_Pre_Edge_Detect(ROI,app)
            set(app.dic_software,'Enable','on')
            set(app.deformed_Image,'Enable','on')
            app.ROI=ROI;
          
        end

        % Menu selected function: Save_as
        function Save_as_Callback(app, event)
            % hObject    handle to Save_as (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            prompt = ['Enter new name'];
            dlgtitle = 'New result file name';
            dims=[2 40];
            opts.Resize ='on';
            answer = inputdlg(prompt,dlgtitle,dims);
            
            if ~isempty(answer)
                if exist('Results.mat')
                    newName = strcat(answer,'.mat');
                    newName =string(newName);
                    movefile('Results.mat',  newName); space= ' ';
                    h=msgbox(['Result file name changed to', space, newName],'Success');
                    set(h, 'position', [500 300 300 60])
                    uiwait(h)
                else
                    h=msgbox('No default result file found','Error','error');
                    set(h, 'position', [500 300 200 60])
            
                    uiwait(h)
                  
                end
            end
        end

        % Menu selected function: Scale_image
        function Scale_image_Callback(app, event)
            % hObject    handle to Scale_image (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            prompt = {'Enter Scale value'};
            dlgtitle = 'Scale the image by:';
            dims=[2 40];
            opts.Resize ='on';
            definput = {'1'};
            answer = inputdlg(prompt,dlgtitle,dims, definput,opts);
            scale_val = str2double(answer);
            if isempty(scale_val)
            else
                app.Scale_val=scale_val;
                set(app.ROI_select,'Enable','on')
            end
            
        end

        % Button pushed function: Speckle_Image
        function Speckle_Image_Callback(app, event)
            % hObject    handle to Speckle_Image (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % no_images=app.no_images;
           
            no_images= app.no_images;
            [ref_speckle_Image, path] = uigetfile({'*png;*jpg;*tiff;*bmp'});
            if ~isequal(ref_speckle_Image,0)
                tilt =fullfile(path,ref_speckle_Image); space= ' ';
                [~,name] = fileparts( tilt);
                set(app.RefImage_panel,'Title', [' Reference Speckle Image name is ',space, name])
                ref_speckle_Image=imread(tilt);
                imshow(ref_speckle_Image,'Parent',app.axes1)
                app.ref_speckle_Image=ref_speckle_Image;
            
                if size(ref_speckle_Image,3)==3
                    ref_speckle_Image = rgb2gray(ref_speckle_Image);
                end
            
                load('Taps_Drawings.mat');
                save('Taps_Drawings_Fabric.mat','Taps_Drawings');
                Taps_Drawings{1,2} = imresize(ref_speckle_Image ,size(Taps_Drawings{1,2}));
            
                save('Taps_Drawings.mat','Taps_Drawings');   % The speckle image is saved in the original copy of Taps_Drawings
                clear Taps_Drawings ref_speckle_Image
                f= waitbar(0,'Generating deformed speckle images');
                for i = 0:no_images
                    Synthetic_Defor(i,app);
                    waitbar(i/no_images)
                end
                delete(f)
                clear i
            
                speckle_dir = ['Speckle_Images'];
                mkdir(speckle_dir);
            
                for i = 0:no_images
                    if i<10
                        tit = ['frame_0000',num2str(i),'.tiff'];
                    end
                    if i>9
                        tit = ['frame_000',num2str(i),'.tiff'];
                    end
                    movefile(tit,speckle_dir);
                end
            
                clear i speckle_dir tit
            
                h=msgbox('Speckle deformed images generated','Comfirm','modal');
                set(h, 'position', [500 300 200 60])
                uiwait(h)
            
                h=msgbox('Use the deformed images in the Deformed Image Folder to perform DIC analyis','Tip');
                set(h, 'position', [500 300 300 60])
                uiwait(h)
            
                set(app.deformed_Image_folder,'Enable','on')
                set(app.Postprocess,'Enable','on')
            
             
            end
        end

        % Menu selected function: Static
        function Static_Callback(app, event)
            % hObject    handle to Static (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            prompt = {'Enter Number of Materials in the Image'};
            dlgtitle = 'Number of Materials:';
            dims=[2 40];
            opts.Resize ='on';
            definput = {'1'};
            answer = inputdlg(prompt,dlgtitle,dims, definput,opts);
            NumMaterials = str2double(answer);
            if ~isempty(NumMaterials)
                app.NumMaterials=NumMaterials;
                app.Explicit=0;
                app.static=1;
                set(app.Material_Behaviour,'Enable','on')
            end
          
        end

        % Menu selected function: Vic
        function Vic_Callback(app, event)
            % hObject    handle to Vic (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.DIC_SOFTWARE =0;
            
            
        end

        % Button pushed function: deformed_Image
        function deformed_Image_Callback(app, event)
            % hObject    handle to deformed_Image (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            Table_load = app.Tabular_Load;
            app.tilt=1;
            app.loaded_analysis=0;
            if app.Explicit==1 && app.Slits==1  % TELL USER TO USE STATIC FOR TEARS
            
                h=msgbox('You have to use Static Analysis to model tears','Error');
                set(h, 'position', [500 300 200 60])
                uiwait(h)
                return
            end
            
           
            %%%%%% Inform user to use explicit dynamics for large deformation
            if app.static==1 && app.Gravity_load==1 && app.Gravity>8500 && app.actal_dimensions==1
                h=msgbox('Large deformation is expected, please use Explicit dynamics option','Error');
                set(h, 'position', [500 500 250 60])
                uiwait(h)
                return
            end
            
            %%%%% Ask if the user want to plot mesh
             Plot_FEA_Mesh=questdlg(' Do you want to view the FEA mesh?','FEA mesh plot','Yes','No','Yes');
            if isempty (Plot_FEA_Mesh)
                return
            elseif (strcmp(Plot_FEA_Mesh,'No'))
           app.Plot_Mesh=0;
            else
             app.Plot_Mesh=1;   
            end


            FEASOFTWARE=questdlg(' Do you want to use ABAQUS for the FEA simulation?','FEA Software','Yes','No','Yes');
            if isempty (FEASOFTWARE)
                return
            end


            if (strcmp(FEASOFTWARE,'No'))
                app.FEA_SOFTWARE=0;
                
                %%%%% TELL USER THAT ONLY LINEAR ELASTIC MATERIAL BEHAVIOUR IS
            %%%%% AVAILABLE FOR THE INBUIT FEA FUNCTION
                 
              if app.Explicit==1 || strcmp(app.Material_Prop,'Hyper_elastic') || app.Slits==1 && app.FEA_SOFTWARE==0
                h=msgbox('ABAQUS FEA is required for this analysis. Only elastic and static analysis without tears can be performed with the in-built FEA.','Error');
                set(h, 'position', [500 500 280 70])
                uiwait(h)
                return
              end
              
              
                switch Table_load
                    case 1
                        no_images=app.no_images;
                        set(app.deformed_Image_folder,'Enable','on')
                        ABAQUS_SIMULATION(app);
            
                    case 0
                        prompt = {'Enter the required number of deformed images'};
                        dlgtitle = 'Number of deformed images:';
                        dims=[2 40];
                        opts.Resize ='on';
                        definput = {'5'};
                        answer = inputdlg(prompt,dlgtitle,dims, definput,opts);
                        no_images = str2double(answer);
                        if ~isempty(no_images)
                            app.no_images = no_images;
                            ABAQUS_SIMULATION(app);
                        else
                            return
                        end
                end
            
            elseif (strcmp(FEASOFTWARE,'Yes'))
                app.FEA_SOFTWARE=1;
            
                odbFile_source=questdlg(' Do you want to use external ABAQUS odb file for this analysis?','odbFile','Yes','No','Yes');
                if isempty ( odbFile_source)
                    return
                end
            
                switch  odbFile_source
                    case'Yes'
                        app.OBD=1;
                        ComfirmUpdrade=questdlg('Is the external Abaqus version same as in your PC?','UpgradeRequired','Yes','No','Yes');
                        switch  ComfirmUpdrade
                            case 'Yes'
                                app.UpgradeRequired=0;  % No upgrade for Abaqus odb file
                            case 'No'
                                app.UpgradeRequired=1;  % Need to upgrade Abaqus odb file
                        end
            
                    case 'No'
                        app.OBD=0;
                end
            
            
                if Table_load==1
                    no_images=app.no_images;
                    set(app.deformed_Image_folder,'Enable','on')
                    ABAQUS_SIMULATION(app);
                else
            
                    prompt = {'Enter the required number of deformed images'};
                    dlgtitle = 'Number of deformed images:';
                    dims=[2 40];
                    opts.Resize ='on';
                    definput = {'5'};
                    answer = inputdlg(prompt,dlgtitle,dims, definput,opts);
                    no_images = str2double(answer);
                    if ~isempty(no_images)
                        app.no_images = no_images;
                        ABAQUS_SIMULATION(app);
                    else
                        return
                    end
                end
            end


            clc
            try
                f= waitbar(0,'Please wait, Generating deformed images');
            
                for i = 0:no_images
            
                    Synthetic_Defor(i,app);
                    waitbar(i/no_images)
                end
                delete(f)
                clear i
                fabric_dir = ['Fabric_Images'];
                mkdir(fabric_dir);
                for i = 0:no_images
                    if i<10
                        tit = ['frame_0000',num2str(i),'.tiff'];
                    end
                    if i>9
                        tit = ['frame_000',num2str(i),'.tiff'];
                    end
                    movefile(tit,fabric_dir);   % by recreating the image file names using the loop above, then movefile command will move the images with the same to the named directory(fabric_dir)
                end
                clear i fabric_dir tit
            
                h=msgbox('Fabric deformed images generated','Deformation successful','modal');
                set(h, 'position', [500 300 150 60])
                uiwait(h)
            
                Specklebutton=questdlg('Do you want to compare results with a speckle pattern image?','Compare speckle','Yes','No','Yes');
            
                if (strcmp(Specklebutton,'Yes'))
                    app.Compare= 'YES';
                    set(app.Speckle_Image,'Enable','on')
            
                    h=msgbox('Press the speckle button to generate deformed speckle images','Speckle','modal');
                    set(h, 'position', [500 300 300 60])
                    uiwait(h)
            
                else
                    app.Compare= 'NO';
                    set(app.deformed_Image_folder,'Enable','on')
                    set(app.Postprocess,'Enable','on')
            
                    h=msgbox('Use the deformed fabric images in the deformed image folder to perform DIC analyis and then copy the results to the defomred image folder','Image folder');
                    set(h, 'position', [500 300 400 60])
                    uiwait(h)
            
                    set(app.dic_software,'Enable','on')
                end
            catch
                delete(f)
                h=msgbox('Check that you have access to Abaqus','Abaqus Error');
                set(h, 'position', [500 300 200 60])
                uiwait(h)
            
            end
            
           
        end

        % Menu selected function: deformed_Image_folder
        function deformed_Image_folder_Callback(app, event)
            % hObject    handle to deformed_Image_folder (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)#
            
            file = uigetfile({'*tiff;*jpg;*png;*bmp;'},'MultiSelect', 'on');
        end

        % Menu selected function: displacement
        function displacement_Callback(app, event)
            % hObject    handle to displacement (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            Dispbutton=questdlg('Is displacement time dependent?','Displacement Load Confirmation','Yes','No','Yes');
            
            if isempty(Dispbutton)
                return
            end
            if (strcmp(Dispbutton,'No'))
                app.time=1;
                app.Tabular_Load =0;
                app.Displacement_load =1;
                app.Gravity_load =0;
                prompt = {'Enter the percent of stretch (Do not add percent sign)'};
                dlgtitle = 'Stretch Percent ';
                dims=[2 40];
                opts.Resize ='on';
                definput = {'1'};
                answer = inputdlg(prompt,dlgtitle,dims, definput,opts);
                strch = str2double(answer);
                strch= -(strch/100);
                app.strch =  strch;
                set(app.Image_processing,'Enable','on')
                set(app.Mesh_Density,'Enable','on')
            
            else
            
                table_confirm = questdlg('How would you input time and load values?', 'Table Value','Read from a file', 'Type into a table','Read from a file');
                if isempty(table_confirm)
                    return
                end
                prompt = {'Enter the total time value'};
                dlgtitle = 'Total time';
                dims=[2 40];
                opts.Resize ='on';
                definput = {'5'};
                answer = inputdlg(prompt,dlgtitle,dims, definput,opts);
            
                if isempty(answer)
                else
                    table_length = str2double(answer);
                    switch table_confirm
                        case 'Type into a table'
            
                            figg = uifigure('Position',[500 200 600 300]);
                            ut = uitable(figg);
                            ut.Position = [50 50 420 320];
                            ut.ColumnName = {'Time', 'Value'};
                            ut.ColumnEditable = true;
                            set(ut, 'data', zeros( table_length,2));
                            waitfor(uibutton(figg,'push', 'Text', 'OK', 'Position', [100 20 50 20], 'ButtonPushedFcn',@(ub,event) getTabledata(ut,figg)));
                            data_table = getappdata(0,'Table_data');
                            app.Data_table=data_table;
            
                        case 'Read from a file'
            
                            %        table_file =readtable(uigetfile({'*txt; *xlsx; *xlsx;'}));
            
                            [filename, path] = uigetfile({'*txt;*xlsx;*xls;'});
                            if isequal(filename,0)
                            else
                                tilt_table =fullfile(path,filename);
                                %                     table_file =readtable(tilt_table);
                                table_file = dlmread(tilt_table);
                                app.Data_table=table_file;
                                app.Data_table=table_file;
            
                            end
                    end
            
                    app.Displacement_load =1;
                    app.Tabular_Load =1;
                    app.Gravity_load =0;
                    app.time=table_length;
                    app.no_images=table_length;
                    app.strch =  0;
                    set(app.Mesh_Density,'Enable','on')
                    set(app.Image_processing,'Enable','on')
                end
            
            end
          
        end

        % Menu selected function: elastic
        function elastic_Callback(app, event)
            % hObject    handle to elastic (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            NumMat=app.NumMaterials;
            
            for Num = 1:NumMat
                Num_count = num2str(Num);
                tit1 =['Enter Youngs Modulus for Material', Num_count];
                tit2 =['Enter Poisson ratio for Material', Num_count];
                prompt = [{tit1, tit2} ];
                dlgtitle = 'Material properties';
                dims=[2 40];
                opts.Resize ='on'; opts.WindowStyle ='modal';
                definput = {'1',' 0'};
            
                answer = inputdlg(prompt,dlgtitle,dims, definput,opts);
                if isempty(answer)
                    break
                else
                    Young = str2double(answer(1));
                    Poisson = str2double(answer(2));
                    Youngs(:,Num)=Young;
                    Poissons(:,Num)=Poisson;
                    if Num==NumMat
                        app.Youngs = Youngs;
                        app.Poissons = Poissons;
            
                        app.Material_Prop='Linear_elastic';
            
                        set(app.Load_type,'Enable','on')
                    end
                end
            end
            
        end

        % Menu selected function: hyper_elastic
        function hyper_elastic_Callback(app, event)
            % hObject    handle to hyper_elastic (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            NumMat=app.NumMaterials;
            for Num = 1:NumMat
                Num_count = num2str(Num);
                tit1 =['Enter \mu value for Material', Num_count];
                tit2 =['Enter \alpha value for Material', Num_count];
                tit3 =['Enter D value for  Material', Num_count,];
                prompt = {tit1, tit2, tit3} ;
                dlgtitle = 'Material properties';
                dims=[2 40];
                opts.Resize ='on'; opts.Interpreter = 'tex';
                definput = {'1',' 1','0'};
                answer = inputdlg(prompt,dlgtitle,dims, definput,opts);
                if isempty(answer)
                    break
                else
                    mu = str2double(answer(1));
                    alpha = str2double(answer(2));
                    D = str2double(answer(3));
                    mu_vals (:,Num)=mu;
                    alpha_vals(:,Num)=alpha;
                    D_vals(:,Num)=D;
                    if Num==NumMat
                        app.mu = mu_vals;
                        app.alpha = alpha_vals;
                        app.D = D_vals;
                        app.Material_Prop='Hyper_elastic';
                        set(app.Load_type,'Enable','on')
                    end
                end
            end
            
        end

        % Button pushed function: push_Increase
        function push_Increase_Callback(app, event)
            % hObject    handle to push_Increase (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            no_images =app.no_images;
            str=string(get(app.resultNum_disp,'Value'));
            currentNum=sscanf(str,'Image number  %d ');
            currentNum=currentNum+1;
            if currentNum>no_images
                currentNum=no_images;
            end
            
            DIC= app.DIC_SOFTWARE;
            Compare =app.Compare;
            abaqus_var=app.abaqus_var;
            tit= app.tilt;  Result_type=app.Result_type;
            Analysis_status =app.loaded_analysis;
            titl=app.titl;
            plotsNew(DIC,abaqus_var,currentNum,Compare,Result_type,Analysis_status,tit,app)
            space ='  '; num=num2str(currentNum);
            set(app.RefImage_panel,'Title', [titl, space, num])
            set(app.resultNum_disp,'Value', ['Image number',space, num])
            
        end

        % Button pushed function: pushdecrease
        function pushdecrease_Callback(app, event)
            % hObject    handle to pushdecrease (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            DIC= app.DIC_SOFTWARE;
            Compare =app.Compare;
            abaqus_var=app.abaqus_var;
            tit= app.tilt;  Result_type=app.Result_type;
            Analysis_status =app.loaded_analysis;
            str=(get(app.resultNum_disp,'Value'));
            str=(str);
            currentNum=sscanf(str,'Image number  %d ');
            currentNum=currentNum-1;
            if currentNum<=0
                currentNum=1;
            end
            titl=app.titl;
            plotsNew(DIC,abaqus_var,currentNum,Compare,Result_type,Analysis_status,tit,app)
            space ='  '; num=num2str(currentNum);
            set(app.RefImage_panel,'Title', [titl, space, num])
            set(app.resultNum_disp,'Value', ['Image number',space, num])
            
        end

        % Menu selected function: save_current_analysis
        function save_current_analysis_Callback(app, event)
            % hObject    handle to save_current_analysis (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            SaveState(app)
        end

        % Menu selected function: shear_diff
        function shear_diff_Callback(app, event)
            % hObject    handle to shear_diff (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            DIC= app.DIC_SOFTWARE;
            no_images =app.no_images;
            abaqus_var =5;   Compare =app.Compare;
            Result_type =4;app.Result_type=Result_type;
            Analysis_status =app.loaded_analysis;
            tit= app.tilt;app.abaqus_var=abaqus_var;
            plotsNew(DIC,abaqus_var,no_images,Compare,Result_type,Analysis_status,tit,handles)
            space ='  '; num=num2str(no_images);
            app.titl= 'Shear strain difference plot for image number';
            set(app.RefImage_panel,'Title', ['Shear strain difference plot for image number', space, num])
            set(app.resultNum_disp,'Visible','on','String', ['Image number',space, num])
            set(app.pushdecrease,'Visible','on')
            set(app.push_Increase,'Visible','on')
            set(app.text3,'Visible','on', 'String', ['Total number of deformed images is', space, num])
            
        end

        % Menu selected function: shear_strain
        function shear_strain_Callback(app, event)
            % hObject    handle to shear_strain (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            DIC= app.DIC_SOFTWARE;
            no_images =app.no_images;
            abaqus_var =5;   Compare =app.Compare;
            Result_type =2;app.Result_type=Result_type;
            Analysis_status =app.loaded_analysis;
            tit= app.tilt;app.abaqus_var=abaqus_var;
            plotsNew(DIC,abaqus_var,no_images,Compare,Result_type,Analysis_status,tit,handles)
            space ='  '; num=num2str(no_images);
            app.titl= 'Shear strain for image number';
            set(app.RefImage_panel,'Title', ['Shear strain for image number', space, num])
            set(app.resultNum_disp,'Visible','on','String', ['Image number',space, num])
            set(app.text3,'Visible','on', 'String', ['Total number of deformed images is', space, num])
            set(app.pushdecrease,'Visible','on')
            set(app.push_Increase,'Visible','on')
            
        end

        % Menu selected function: shear_strain_glob
        function shear_strain_glob_Callback(app, event)
            % hObject    handle to shear_strain_glob (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            DIC= app.DIC_SOFTWARE;
            no_images =app.no_images;
            abaqus_var =5;   Compare =app.Compare;
            Result_type =5;
            Analysis_status =app.loaded_analysis;
            tit= app.tilt;
            plotsNew(DIC,abaqus_var,no_images,Compare,Result_type,Analysis_status,tit,handles)
            set(app.RefImage_panel,'Title', ['Global Average plot for shear strain'])
        end

        % Menu selected function: shear_strain_percent
        function shear_strain_percent_Callback(app, event)
            % hObject    handle to shear_strain_percent (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            DIC= app.DIC_SOFTWARE;
            no_images =app.no_images;
            abaqus_var =5;   Compare =app.Compare;
            Result_type =3;app.Result_type=Result_type;
            Analysis_status =app.loaded_analysis;
            tit= app.tilt;app.abaqus_var=abaqus_var;
            plotsNew(DIC,abaqus_var,no_images,Compare,Result_type,Analysis_status,tit,handles)
            space ='  '; num=num2str(no_images);
            app.titl= 'Shear strain percent error for image number';
            
            set(app.RefImage_panel,'Title', ['Shear strain percent error for image number', space, num])
            set(app.resultNum_disp,'Visible','on','String', ['Image number',space, num])
            set(app.text3,'Visible','on', 'String', ['Total number of deformed images is', space, num])
            set(app.pushdecrease,'Visible','on')
            set(app.push_Increase,'Visible','on')
            guidata(hObject,handles)
        end

        % Menu selected function: slits
        function slits_Callback(app, event)
            % hObject    handle to slits (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %THIS FUNCTION ALLOWS THE USER TO ADD TEARS IN THE IMAGE
            
            Confirm=questdlg('Do you want to add tears in the image?','Confirm','Yes','No','Yes');
            if strcmp(Confirm,'Yes')
                prompt = {'Enter the number of tears in the image'};
                dlgtitle = 'Number of tears';
                dims=[1 50];
                opts.Resize ='on';
                definput = {'1'};
                answer = inputdlg(prompt,dlgtitle,dims, definput,opts);
                answer= str2double(answer);
                if ~isempty(answer)
                    app.Slits=1;
                    list={'Square or rectangle tears','Line tears'};
                    SlitType = listdlg('ListString',list,'SelectionMode','single','ListSize',[200 100],'Name', 'Select type');
                    app.SlitType=SlitType;
             f = figure('NumberTitle','off','Name','Draw tears in the image','Visible','on','MenuBar','none');
             imshow(app.ref_Image)
                    switch SlitType
                        case 1     %  Square or recta
            
                            for istitch=1:answer
                                slitElem = user_selct_Square(app);
                                pos(1,1)= slitElem (1); pos(1,2)= slitElem(3); pos(1,3)= slitElem (2)-slitElem (1);   pos(1,4)= slitElem(4)-slitElem (3);
                                pos = round(pos);   % Position of tear in image coordinate
                                app.ref_Image(pos(1,2): pos(1,2)+ pos(1,4),pos(1,1):pos(1,1)+ pos(1,3))=0;
                                app.SlittedElements(istitch,:)=slitElem;
            
                            end
                        case 2     % Line stichtes
                            for istitch=1:answer
                                slitElem  =  getLine(app);
                                pos(1,1)= slitElem (1); pos(1,2)= slitElem(3); pos(1,3)= slitElem (2)-slitElem (1);   pos(1,4)= slitElem(4)-slitElem (3);
                                pos = round(pos);   % Position of tear in image coordinate
            
                                app.ref_Image(pos(1,2): pos(1,2)+ pos(1,4),pos(1,1):pos(1,1)+ pos(1,3))=0;
                                app.SlittedElements(istitch,:)=  slitElem ;
           
                            end
                    end
                    close (f)
                    imshow(app.ref_Image,'Parent',app.axes1);
                else
                    return
                end
            
            
            else
                return
            end
            
        end

        % Menu selected function: stitches
        function stitches_Callback(app, event)
            % hObject    handle to stitches (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            Confirm=questdlg('Do you want to add patched stitches?','Confirm','Yes','No','Yes');
            if strcmp(Confirm,'Yes')
                prompt = {'Enter the number of stitches in the image'};
                dlgtitle = 'Number of stitches';
                dims=[1 50];
                opts.Resize ='on';
                definput = {'1'};
                answer = inputdlg(prompt,dlgtitle,dims, definput,opts);
                answer= str2double(answer);
                if ~isempty(answer)
                    app.SticthedElement=1;
                    list={'Square or rectangle stitches','Line stitches'};
                    StitchType = listdlg('ListString',list,'SelectionMode','single','ListSize',[200 100],'Name', 'Select type');
                    f = figure('NumberTitle','off','Name','Draw patches in the image','Visible','on','MenuBar','none');
                    imshow(app.ref_Image)           
                    switch StitchType
                        case 1     %  Square or recta
            
                            for istitch=1:answer
                                stitchedElem = user_selct_Square(app);
                                app.StitchedElements(istitch,:)=stitchedElem;
            
                            end
                        case 2     % Line stichtes
                            for istitch=1:answer
            
                            end
            
                    end
                    
                    for patch= 1: size(app.StitchedElements,1)
                        Pos=app.StitchedElements(patch,:);
                        pos(1) = Pos(1); pos(2) =Pos(3);  pos(3) =Pos(2)-Pos(1);  pos(4) =Pos(4)-Pos(3);
                      rectangle(app.axes1,'Position', [pos],'FaceColor','b')
                        
                    end
                    close (f)


                    if strcmp(app.Material_Prop,'Linear_elastic')
            
                        tit1 =['Enter Youngs Modulus for patching material'];
                        tit2 =['Enter Poisson ratio for patching material'];
                        prompt = [{tit1, tit2} ];
                        dlgtitle = 'Patching material properties';
                        dims=[2 40];
                        opts.Resize ='on'; opts.WindowStyle ='modal';
                        definput = {'1',' 0'};
            
                        answer = inputdlg(prompt,dlgtitle,dims, definput,opts);
                        if isempty(answer)
                            return
                        else
                            app.PatchedYoungs  = str2double(answer(1));
                            app.PatchedPoissons= str2double(answer(2));
                        end
            
                    elseif strcmp(app.Material_Prop,'Hyper_elastic')
            
                        tit1 =['Enter \mu value for the patched material'];
                        tit2 =['Enter \alpha value for the patched material'];
                        tit3 =['Enter D value for the patched  material'];
                        prompt = {tit1, tit2, tit3} ;
                        dlgtitle = 'Material properties';
                        dims=[2 40];
                        opts.Resize ='on'; opts.Interpreter = 'tex';
                        definput = {'1',' 1','0'};
                        answer = inputdlg(prompt,dlgtitle,dims, definput,opts);
                        if isempty(answer)
                            return
                        else
                            app.Patch_mu = str2double(answer(1));
                            app.Patch_alpha = str2double(answer(2));
                            app.Patch_D = str2double(answer(3));
                            
                        end
            
                    end  % end for materials


                else    %User did not give details of patches
            
                    return
            
                end
            else                  %User does want to add patches
            
                return
            end
           
        end

        % Menu selected function: x_disp_diff
        function x_disp_diff_Callback(app, event)
            % hObject    handle to x_disp_diff (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            DIC= app.DIC_SOFTWARE;
            no_images =app.no_images;
            abaqus_var =1;   Compare =app.Compare;
            Result_type =4;app.abaqus_var=abaqus_var;
            Analysis_status =app.loaded_analysis;
            tit= app.tilt;app.Result_type=Result_type;
            plotsNew(DIC,abaqus_var,no_images,Compare,Result_type,Analysis_status,tit,app)
            space ='  '; num=num2str(no_images);
            app.titl= 'X displacement difference plot for image number';
            set(app.RefImage_panel,'Title', ['X displacement difference plot for image number', space, num])
            set(app.resultNum_disp,'Visible','on','Value', ['Image number',space, num])
            set(app.pushdecrease,'Visible','on')
            set(app.push_Increase,'Visible','on')
            set(app.text3,'Visible','on', 'Text', ['Total number of deformed images is', space, num])
           
        end

        % Menu selected function: x_disp_glob
        function x_disp_glob_Callback(app, event)
            % hObject    handle to x_disp_glob (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            DIC= app.DIC_SOFTWARE;
            no_images =app.no_images;
            abaqus_var =1;   Compare =app.Compare;
            Result_type =5;
            Analysis_status =app.loaded_analysis;
            tit= app.tilt;
            plotsNew(DIC,abaqus_var,no_images,Compare,Result_type,Analysis_status,tit,app)
            set(app.RefImage_panel,'Title', ['Global Average plot for X displacement'])
        end

        % Menu selected function: x_disp_percent
        function x_disp_percent_Callback(app, event)
            % hObject    handle to x_disp_percent (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            DIC= app.DIC_SOFTWARE;
            no_images =app.no_images;
            abaqus_var =1;   Compare =app.Compare;
            Result_type =3;app.Result_type=Result_type;
            Analysis_status =app.loaded_analysis;
            tit= app.tilt;app.abaqus_var=abaqus_var;
            plotsNew(DIC,abaqus_var,no_images,Compare,Result_type,Analysis_status,tit,app)
            space ='  '; num=num2str(no_images);
            app.titl= 'X displacement percent error for image number';
            set(app.RefImage_panel,'Title', ['X displacement percent error for image number', space, num])
            set(app.resultNum_disp,'Visible','on','Value', ['Image number',space, num])
            set(app.pushdecrease,'Visible','on')
            set(app.push_Increase,'Visible','on')
            set(app.text3,'Visible','on', 'Text', ['Total number of deformed images is', space, num])
           
        end

        % Menu selected function: x_disp_plot
        function x_disp_plot_Callback(app, event)
            % hObject    handle to x_disp_plot (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            DIC= app.DIC_SOFTWARE;
            no_images =app.no_images;
            abaqus_var =1;   Compare =app.Compare;
            Result_type =1;
            app.abaqus_var=abaqus_var;
            Analysis_status =app.loaded_analysis;
            tit= app.tilt;app.Result_type=Result_type;
            plotsNew(DIC,abaqus_var,no_images,Compare,Result_type,Analysis_status,tit,app)
            space ='  '; num=num2str(no_images);
            app.titl= 'X displacement for image number';
            set(app.RefImage_panel,'Title', ['X displacement for image number', space, num])
            set(app.resultNum_disp,'Visible','on','Value', ['Image number',space, num])
            set(app.pushdecrease,'Visible','on')
            set(app.push_Increase,'Visible','on')
            set(app.text3,'Visible','on', 'Text', ['Total number of deformed images is', space, num])
          
        end

        % Menu selected function: x_strain
        function x_strain_Callback(app, event)
            % hObject    handle to x_strain (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            DIC= app.DIC_SOFTWARE;
            no_images =app.no_images;
            abaqus_var =3;   Compare =app.Compare;
            Result_type =2;Analysis_status =app.loaded_analysis;
            tit= app.tilt;app.abaqus_var=abaqus_var;app.Result_type=Result_type;
            plotsNew(DIC,abaqus_var,no_images,Compare,Result_type,Analysis_status,tit,app)
            space ='  '; num=num2str(no_images);
            app.titl= 'X strain for image number';
            set(app.RefImage_panel,'Title', ['X strain for image number', space, num])
            set(app.resultNum_disp,'Visible','on','Value', ['Image number',space, num])
            set(app.text3,'Visible','on', 'Text', ['Total number of deformed images is', space, num])
            set(app.pushdecrease,'Visible','on')
            set(app.push_Increase,'Visible','on')
           
        end

        % Menu selected function: x_strain_diff
        function x_strain_diff_Callback(app, event)
            % hObject    handle to x_strain_diff (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            DIC= app.DIC_SOFTWARE;
            no_images =app.no_images;
            abaqus_var =3;   Compare =app.Compare;
            Result_type =4;app.Result_type=Result_type;
            Analysis_status =app.loaded_analysis;
            tit= app.tilt;app.abaqus_var=abaqus_var;
            plotsNew(DIC,abaqus_var,no_images,Compare,Result_type,Analysis_status,tit,app)
            space ='  '; num=num2str(no_images);
            app.titl= 'X strain difference plot for image number';
            set(app.RefImage_panel,'Title', ['X strain difference for image number', space, num])
            set(app.resultNum_disp,'Visible','on','Value', ['Image number',space, num])
            set(app.pushdecrease,'Visible','on')
            set(app.push_Increase,'Visible','on')
            set(app.text3,'Visible','on', 'Text', ['Total number of deformed images is', space, num])
            
        end

        % Menu selected function: x_strain_glob
        function x_strain_glob_Callback(app, event)
            % hObject    handle to x_strain_glob (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            DIC= app.DIC_SOFTWARE;
            no_images =app.no_images;
            abaqus_var =3;   Compare =app.Compare;
            Result_type =5;
            Analysis_status =app.loaded_analysis;
            tit= app.tilt;
            plotsNew(DIC,abaqus_var,no_images,Compare,Result_type,Analysis_status,tit,app)
            set(app.RefImage_panel,'Title', ['Global Average plot for X strain'])
        end

        % Menu selected function: x_strain_percent
        function x_strain_percent_Callback(app, event)
            % hObject    handle to x_strain_percent (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            DIC= app.DIC_SOFTWARE;
            no_images =app.no_images;
            abaqus_var =3;   Compare =app.Compare;
            Result_type =3;app.Result_type=Result_type;
            Analysis_status =app.loaded_analysis;
            tit= app.tilt;app.abaqus_var=abaqus_var;
            plotsNew(DIC,abaqus_var,no_images,Compare,Result_type,Analysis_status,tit,app)
            space ='  '; num=num2str(no_images);
            app.titl= 'X strain percent error for image number';
            set(app.RefImage_panel,'Title', ['X strain percent error for image number', space, num])
            set(app.resultNum_disp,'Visible','on','Value', ['Image number',space, num])
            set(app.text3,'Visible','on', 'Text', ['Total number of deformed images is', space, num])
            set(app.pushdecrease,'Visible','on')
            set(app.push_Increase,'Visible','on')
            
        end

        % Menu selected function: y_disp_diff
        function y_disp_diff_Callback(app, event)
            % hObject    handle to y_disp_diff (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            DIC= app.DIC_SOFTWARE;
            no_images =app.no_images;
            abaqus_var =2;   Compare =app.Compare;
            Result_type =4;app.Result_type=Result_type;
            Analysis_status =app.loaded_analysis;
            tit= app.tilt;app.abaqus_var=abaqus_var;
            plotsNew(DIC,abaqus_var,no_images,Compare,Result_type,Analysis_status,tit,app)
            space ='  '; num=num2str(no_images);
            app.titl= 'Y displacement difference plot for image number';
            set(app.RefImage_panel,'Title', ['Y displacement difference plot for image number', space, num])
            set(app.resultNum_disp,'Visible','on','Value', ['Image number',space, num])
            set(app.pushdecrease,'Visible','on')
            set(app.push_Increase,'Visible','on')
            set(app.text3,'Visible','on', 'Text', ['Total number of deformed images is', space, num])
          
        end

        % Menu selected function: y_disp_glob
        function y_disp_glob_Callback(app, event)
            % hObject    handle to y_disp_glob (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            DIC= app.DIC_SOFTWARE;
            no_images =app.no_images;
            abaqus_var =2;   Compare =app.Compare;
            Result_type =5;
            Analysis_status =app.loaded_analysis;
            tit= app.tilt;
            plotsNew(DIC,abaqus_var,no_images,Compare,Result_type,Analysis_status,tit,app)
            space ='  '; num=num2str(no_images);
            set(app.RefImage_panel,'Title', ['Global Average plot for Y displacement'])
        end

        % Menu selected function: y_disp_percent
        function y_disp_percent_Callback(app, event)
            % hObject    handle to y_disp_percent (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            DIC= app.DIC_SOFTWARE;
            no_images =app.no_images;
            abaqus_var =2;   Compare =app.Compare;
            Result_type =3;app.Result_type=Result_type;
            Analysis_status =app.loaded_analysis;
            tit= app.tilt;app.abaqus_var=abaqus_var;
            plotsNew(DIC,abaqus_var,no_images,Compare,Result_type,Analysis_status,tit,app)
            space ='  '; num=num2str(no_images);
            app.titl= 'Y displacemnt percent error for image number';
            set(app.RefImage_panel,'Title', ['Y displacement percent error plot for image number', space, num])
            set(app.resultNum_disp,'Visible','on','Value', ['Image number',space, num])
            set(app.pushdecrease,'Visible','on')
            set(app.push_Increase,'Visible','on')
            set(app.text3,'Visible','on', 'Text', ['Total number of deformed images is', space, num])
            
        end

        % Menu selected function: y_disp_plot
        function y_disp_plot_Callback(app, event)
            % hObject    handle to y_disp_plot (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % % handles    structure with handles and user data (see GUIDATA)
            ax=app.axes1;
            DIC= app.DIC_SOFTWARE;
            no_images =app.no_images;
            abaqus_var =2;   Compare =app.Compare;
            Result_type =1;  app.abaqus_var=abaqus_var;
            tit= app.tilt;  app.Result_type=Result_type;
            Analysis_status =app.loaded_analysis;
            %  gui(DIC,abaqus_var,no_images,Compare,Result_type,Analysis_status,tit)
            plotsNew(DIC,abaqus_var,no_images,Compare,Result_type,Analysis_status,tit,app)
            space ='  '; num=num2str(no_images);
            app.titl= 'Y displacement for image number';
            set(app.RefImage_panel,'Title', ['Y displacement for image number', space, num])
            set(app.resultNum_disp,'Visible','on','Value', ['Image number',space, num])
            set(app.pushdecrease,'Visible','on')
            set(app.push_Increase,'Visible','on')
            set(app.text3,'Visible','on', 'Text', ['Total number of deformed images is', space, num])
            
           
        end

        % Menu selected function: y_strain
        function y_strain_Callback(app, event)
            % hObject    handle to y_strain (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Compare = app.compare;  %use this
            
            DIC= app.DIC_SOFTWARE;
            no_images =app.no_images;
            abaqus_var =4;   Compare =app.Compare;
            Result_type =2;
            app.abaqus_var=abaqus_var;
            Analysis_status =app.loaded_analysis;
            tit= app.tilt;app.Result_type=Result_type;
            plotsNew(DIC,abaqus_var,no_images,Compare,Result_type,Analysis_status,tit,app)
            space ='  '; num=num2str(no_images);
            app.titl= 'Y strain for image number';
            set(app.RefImage_panel,'Title', ['Y strain for image number', space, num])
            set(app.resultNum_disp,'Visible','on','Value', ['Image number',space, num])
            set(app.text3,'Visible','on', 'Text', ['Total number of deformed images is', space, num])
            set(app.pushdecrease,'Visible','on')
            set(app.push_Increase,'Visible','on')
           
        end

        % Menu selected function: y_strain_diff
        function y_strain_diff_Callback(app, event)
            % hObject    handle to y_strain_diff (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            DIC= app.DIC_SOFTWARE;
            no_images =app.no_images;
            abaqus_var =4;   Compare =app.Compare;
            Result_type =4;app.abaqus_var=abaqus_var;
            Analysis_status =app.loaded_analysis;
            tit= app.tilt;app.Result_type=Result_type;
            plotsNew(DIC,abaqus_var,no_images,Compare,Result_type,Analysis_status,tit,app)
            space ='  '; num=num2str(no_images);
            app.titl= 'Y strain difference plot for image number';
            set(app.RefImage_panel,'Title', ['Y strain difference plot for image number', space, num])
            set(app.resultNum_disp,'Visible','on','Value', ['Image number',space, num])
            set(app.pushdecrease,'Visible','on')
            set(app.push_Increase,'Visible','on')
            set(app.text3,'Visible','on', 'Text', ['Total number of deformed images is', space, num])
            
        end

        % Menu selected function: y_strain_glob
        function y_strain_glob_Callback(app, event)
            % hObject    handle to y_strain_glob (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            DIC= app.DIC_SOFTWARE;
            no_images =app.no_images;
            abaqus_var =4;   Compare =app.Compare;
            Result_type =5;
            Analysis_status =app.loaded_analysis;
            tit= app.tilt;
            plotsNew(DIC,abaqus_var,no_images,Compare,Result_type,Analysis_status,tit,app)
            set(app.RefImage_panel,'Title', ['Global Average plot for Y strain'])
        end

        % Menu selected function: y_strain_percent
        function y_strain_percent_Callback(app, event)
            % hObject    handle to y_strain_percent (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            DIC= app.DIC_SOFTWARE;
            no_images =app.no_images;
            abaqus_var =4;   Compare =app.Compare;
            Result_type =3;app.Result_type=Result_type;
            Analysis_status =app.loaded_analysis;
            tit= app.tilt;app.abaqus_var=abaqus_var;
            plotsNew(DIC,abaqus_var,no_images,Compare,Result_type,Analysis_status,tit,app)
            space ='  '; num=num2str(no_images);
            app.titl= 'Y strain percent error for image number';
            set(app.RefImage_panel,'Title', ['Y strain percent error for image number', space, num])
            set(app.resultNum_disp,'Visible','on','Value', ['Image number',space, num])
            set(app.text3,'Visible','on', 'Text', ['Total number of deformed images is', space, num])
            set(app.pushdecrease,'Visible','on')
            set(app.push_Increase,'Visible','on')
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Tapestry_ToolKit';
            app.File = uimenu(app.figure1);
            app.File.Text = 'File';
            app.Open_ref_Image = uimenu(app.File);
            app.Open_ref_Image.MenuSelectedFcn = createCallbackFcn(app, @Open_ref_Image_Callback, true);
            app.Open_ref_Image.Text = 'Open Reference Image';
            app.Load_Analysis = uimenu(app.File);
            app.Load_Analysis.MenuSelectedFcn = createCallbackFcn(app, @Load_Analysis_Callback, true);
            app.Load_Analysis.Text = 'Load Analysis Results';
            app.Save_as = uimenu(app.File);
            app.Save_as.MenuSelectedFcn = createCallbackFcn(app, @Save_as_Callback, true);
            app.Save_as.Text = 'Save Results as';
            app.deformed_Image_folder = uimenu(app.File);
            app.deformed_Image_folder.MenuSelectedFcn = createCallbackFcn(app, @deformed_Image_folder_Callback, true);
            app.deformed_Image_folder.Text = 'Deformed Image Folder';
            app.Exit_menu = uimenu(app.File);
            app.Exit_menu.MenuSelectedFcn = createCallbackFcn(app, @Exit_menu_Callback, true);
            app.Exit_menu.Text = 'Exit';
            app.Load_current_analysis = uimenu(app.File);
            app.Load_current_analysis.MenuSelectedFcn = createCallbackFcn(app, @Load_current_analysis_Callback, true);
            app.Load_current_analysis.Text = 'Load Analysis';
            app.save_current_analysis = uimenu(app.File);
            app.save_current_analysis.MenuSelectedFcn = createCallbackFcn(app, @save_current_analysis_Callback, true);
            app.save_current_analysis.Text = 'Save Analysis as';
            app.Analysis_type_selection = uimenu(app.figure1);
            app.Analysis_type_selection.Text = 'Analysis Type';
            app.Static = uimenu(app.Analysis_type_selection);
            app.Static.MenuSelectedFcn = createCallbackFcn(app, @Static_Callback, true);
            app.Static.Text = 'Static';
            app.Explicit_dynamics = uimenu(app.Analysis_type_selection);
            app.Explicit_dynamics.MenuSelectedFcn = createCallbackFcn(app, @Explicit_dynamics_Callback, true);
            app.Explicit_dynamics.Text = 'Explicit dynamics';
            app.Material_Behaviour = uimenu(app.figure1);
            app.Material_Behaviour.Text = 'Material Behaviour';
            app.elastic = uimenu(app.Material_Behaviour);
            app.elastic.MenuSelectedFcn = createCallbackFcn(app, @elastic_Callback, true);
            app.elastic.Text = 'Linear Elastic';
            app.hyper_elastic = uimenu(app.Material_Behaviour);
            app.hyper_elastic.MenuSelectedFcn = createCallbackFcn(app, @hyper_elastic_Callback, true);
            app.hyper_elastic.Text = 'Hyperelastic';
            app.Load_type = uimenu(app.figure1);
            app.Load_type.Text = 'Load Type';
            app.displacement = uimenu(app.Load_type);
            app.displacement.MenuSelectedFcn = createCallbackFcn(app, @displacement_Callback, true);
            app.displacement.Text = 'Displacement (Stretching)';
            app.Gravity = uimenu(app.Load_type);
            app.Gravity.MenuSelectedFcn = createCallbackFcn(app, @Gravity_Callback, true);
            app.Gravity.Text = 'Self Weight (Gravity)';
            app.Image_processing = uimenu(app.figure1);
            app.Image_processing.Separator = 'on';
            app.Image_processing.Text = 'Image Preprocessing';
            app.ROI_select = uimenu(app.Image_processing);
            app.ROI_select.MenuSelectedFcn = createCallbackFcn(app, @ROI_select_Callback, true);
            app.ROI_select.Text = 'Select ROI';
            app.Scale_image = uimenu(app.Image_processing);
            app.Scale_image.MenuSelectedFcn = createCallbackFcn(app, @Scale_image_Callback, true);
            app.Scale_image.Text = 'Scale Image';
            app.Mesh_Density = uimenu(app.Image_processing);
            app.Mesh_Density.MenuSelectedFcn = createCallbackFcn(app, @Mesh_Density_Callback, true);
            app.Mesh_Density.Text = 'Mesh Density';
            app.slits = uimenu(app.Image_processing);
            app.slits.MenuSelectedFcn = createCallbackFcn(app, @slits_Callback, true);
            app.slits.Text = 'Add slits ( tears) to image';
            app.stitches = uimenu(app.Image_processing);
            app.stitches.MenuSelectedFcn = createCallbackFcn(app, @stitches_Callback, true);
            app.stitches.Text = 'Add slit stitches to image';
            app.dic_software = uimenu(app.figure1);
            app.dic_software.Text = 'DIC Software';
            app.Vic = uimenu(app.dic_software);
            app.Vic.MenuSelectedFcn = createCallbackFcn(app, @Vic_Callback, true);
            app.Vic.Text = 'VIC 2D';
            app.Ncorr = uimenu(app.dic_software);
            app.Ncorr.MenuSelectedFcn = createCallbackFcn(app, @Ncorr_Callback, true);
            app.Ncorr.Text = 'Ncorr';
            app.Results = uimenu(app.figure1);
            app.Results.Text = 'Results';
            app.dic_fea_plot = uimenu(app.Results);
            app.dic_fea_plot.Text = 'DIC and FEA Results';
            app.y_disp_plot = uimenu(app.dic_fea_plot);
            app.y_disp_plot.MenuSelectedFcn = createCallbackFcn(app, @y_disp_plot_Callback, true);
            app.y_disp_plot.Text = 'V';
            app.x_disp_plot = uimenu(app.dic_fea_plot);
            app.x_disp_plot.MenuSelectedFcn = createCallbackFcn(app, @x_disp_plot_Callback, true);
            app.x_disp_plot.Text = 'U';
            app.y_strain = uimenu(app.dic_fea_plot);
            app.y_strain.MenuSelectedFcn = createCallbackFcn(app, @y_strain_Callback, true);
            app.y_strain.Text = 'Eyy';
            app.x_strain = uimenu(app.dic_fea_plot);
            app.x_strain.MenuSelectedFcn = createCallbackFcn(app, @x_strain_Callback, true);
            app.x_strain.Text = 'Exx';
            app.shear_strain = uimenu(app.dic_fea_plot);
            app.shear_strain.MenuSelectedFcn = createCallbackFcn(app, @shear_strain_Callback, true);
            app.shear_strain.Text = 'Exy';
            app.Percent_error = uimenu(app.Results);
            app.Percent_error.Text = 'Percent Error';
            app.y_disp_percent = uimenu(app.Percent_error);
            app.y_disp_percent.MenuSelectedFcn = createCallbackFcn(app, @y_disp_percent_Callback, true);
            app.y_disp_percent.Text = 'V percent error ';
            app.x_disp_percent = uimenu(app.Percent_error);
            app.x_disp_percent.MenuSelectedFcn = createCallbackFcn(app, @x_disp_percent_Callback, true);
            app.x_disp_percent.Text = 'U percent error';
            app.y_strain_percent = uimenu(app.Percent_error);
            app.y_strain_percent.MenuSelectedFcn = createCallbackFcn(app, @y_strain_percent_Callback, true);
            app.y_strain_percent.Text = 'Eyy percent error ';
            app.x_strain_percent = uimenu(app.Percent_error);
            app.x_strain_percent.MenuSelectedFcn = createCallbackFcn(app, @x_strain_percent_Callback, true);
            app.x_strain_percent.Text = 'Exx percent error';
            app.shear_strain_percent = uimenu(app.Percent_error);
            app.shear_strain_percent.MenuSelectedFcn = createCallbackFcn(app, @shear_strain_percent_Callback, true);
            app.shear_strain_percent.Text = 'Exy percent error';
            app.difference_plot = uimenu(app.Results);
            app.difference_plot.Text = 'Difference plots';
            app.y_disp_diff = uimenu(app.difference_plot);
            app.y_disp_diff.MenuSelectedFcn = createCallbackFcn(app, @y_disp_diff_Callback, true);
            app.y_disp_diff.Text = 'V difference plot';
            app.x_disp_diff = uimenu(app.difference_plot);
            app.x_disp_diff.MenuSelectedFcn = createCallbackFcn(app, @x_disp_diff_Callback, true);
            app.x_disp_diff.Text = 'U difference plot';
            app.y_strain_diff = uimenu(app.difference_plot);
            app.y_strain_diff.MenuSelectedFcn = createCallbackFcn(app, @y_strain_diff_Callback, true);
            app.y_strain_diff.Text = 'Eyy difference plot';
            app.x_strain_diff = uimenu(app.difference_plot);
            app.x_strain_diff.MenuSelectedFcn = createCallbackFcn(app, @x_strain_diff_Callback, true);
            app.x_strain_diff.Text = 'Exx  difference plot';
            app.shear_diff = uimenu(app.difference_plot);
            app.shear_diff.MenuSelectedFcn = createCallbackFcn(app, @shear_diff_Callback, true);
            app.shear_diff.Text = 'Exy difference plot';
            app.global_aver = uimenu(app.Results);
            app.global_aver.Text = 'Global Average Plots';
            app.y_disp_glob = uimenu(app.global_aver);
            app.y_disp_glob.MenuSelectedFcn = createCallbackFcn(app, @y_disp_glob_Callback, true);
            app.y_disp_glob.Text = 'V global average plot';
            app.x_disp_glob = uimenu(app.global_aver);
            app.x_disp_glob.MenuSelectedFcn = createCallbackFcn(app, @x_disp_glob_Callback, true);
            app.x_disp_glob.Text = 'U global average plot';
            app.y_strain_glob = uimenu(app.global_aver);
            app.y_strain_glob.MenuSelectedFcn = createCallbackFcn(app, @y_strain_glob_Callback, true);
            app.y_strain_glob.Text = 'Eyy global average plot';
            app.x_strain_glob = uimenu(app.global_aver);
            app.x_strain_glob.MenuSelectedFcn = createCallbackFcn(app, @x_strain_glob_Callback, true);
            app.x_strain_glob.Text = 'Exx global average plot';
            app.shear_strain_glob = uimenu(app.global_aver);
            app.shear_strain_glob.MenuSelectedFcn = createCallbackFcn(app, @shear_strain_glob_Callback, true);
            app.shear_strain_glob.Text = 'Exy global average plot';
            app.Recommend = uimenu(app.Results);
            app.Recommend.Text = 'Recommendations';
            app.RefImage_panel = uipanel(app.figure1);
            app.RefImage_panel.Title = 'Reference Image ';
            app.axes1 = uiaxes(app.RefImage_panel);
            app.axes1.Visible = 'off';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Analysis';
            app.deformed_Image = uibutton(app.uipanel2, 'push');
            app.deformed_Image.ButtonPushedFcn = createCallbackFcn(app, @deformed_Image_Callback, true);
            app.deformed_Image.Text = 'Deform Images';
            app.Postprocess = uibutton(app.uipanel2, 'push');
            app.Postprocess.ButtonPushedFcn = createCallbackFcn(app, @Postprocess_Callback, true);
            app.Postprocess.Text = 'PostProcess';
            app.Speckle_Image = uibutton(app.uipanel2, 'push');
            app.Speckle_Image.ButtonPushedFcn = createCallbackFcn(app, @Speckle_Image_Callback, true);
            app.Speckle_Image.Text = {'Speckle image'; 'speckle image'};
            app.pushdecrease = uibutton(app.figure1, 'push');
            app.pushdecrease.ButtonPushedFcn = createCallbackFcn(app, @pushdecrease_Callback, true);
            app.pushdecrease.Text = '<';
            app.resultNum_disp = uieditfield(app.figure1, 'text');
            app.push_Increase = uibutton(app.figure1, 'push');
            app.push_Increase.ButtonPushedFcn = createCallbackFcn(app, @push_Increase_Callback, true);
            app.push_Increase.Text = '>';
            app.text3 = uilabel(app.figure1);
            app.text3.Text = '';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tapestry_ToolKit_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Tapestry_ToolKit_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end